classdef seq_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        ExportTIFFButton          matlab.ui.control.Button
        FileEditField             matlab.ui.control.EditField
        FileEditFieldLabel        matlab.ui.control.Label
        Slider_2                  matlab.ui.control.RangeSlider
        FrameCountEditField       matlab.ui.control.NumericEditField
        FrameCountEditFieldLabel  matlab.ui.control.Label
        CurrentFrameSpinner       matlab.ui.control.Spinner
        CurrentFrameSpinnerLabel  matlab.ui.control.Label
        FFWDButton                matlab.ui.control.Button
        PlayPauseButton           matlab.ui.control.Button
        RWDButton                 matlab.ui.control.Button
        OpenSEQfileButton         matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        seq_file_name % Description
        testing = false % Description
        test_file
    end
    
    methods (Access = private)
        
        function read_frame(app)
            frameNumber = round(app.CurrentFrameSpinner.Value);
            fPath = app.seq_file_name;
            img = seq.readNorpixSeqImage(fPath,'StartFrame',frameNumber);
            if size(img,3)==1
                img = repmat(img,1,1,3);
            end
            app.Image.ImageSource=img;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSEQfileButton
        function OpenSEQfileButtonPushed(app, event)
            
            app.seq_file_name = app.test_file;
            if isempty(app.test_file) || ~isfile(app.test_file)
                [fn,pn]=uigetfile('*.seq');
                app.seq_file_name=fullfile(pn,fn);
            end
            app.FileEditField.Value = app.seq_file_name;
            seq_header=seq.readNorpixSeqHeader(app.seq_file_name);
            nframes=seq_header.AllocatedFrames;
            app.Slider_2.Limits=[1, nframes];
            app.CurrentFrameSpinner.Limits=[1 nframes];
            
            app.FrameCountEditField.Value = nframes;
            app.CurrentFrameSpinner.Value = 1;
            app.read_frame;
        end

        % Callback function: CurrentFrameSpinner, CurrentFrameSpinner
        function CurrentFrameSpinnerValueChanged(app, event)
            sl_value = app.Slider_2.Value;

            fr = app.CurrentFrameSpinner.Value;
            if fr>sl_value(2)
                app.Slider_2.Value(2) = fr;
            else
                app.Slider_2.Value(1) = fr;
            end
            
            app.read_frame;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            oldVal = event.PreviousValue;
            dd = abs(value - oldVal)>0;
            val = value(dd);
            val = val(1);
            app.CurrentFrameSpinner.Value = round(val);
            app.read_frame;

        end

        % Button pushed function: ExportTIFFButton
        function ExportTIFFButtonPushed(app, event)
            [pn,fn,ext] = fileparts(app.seq_file_name);
            tiff_folder = fullfile(pn,fn);
            if ~isfolder(tiff_folder)
                mkdir(tiff_folder)
            end
            frame_range = app.Slider_2.Value;
            %for i = frame_range(1):frame_range(2)
            img = seq.readNorpixSeqImage(app.seq_file_name, ...
                'StartFrame',frame_range(1),'EndFrame',frame_range(2));
            for i=1:size(img,3)
                fr_id = frame_range(1)+i-1;
                tiff_name = fn + "_"+ num2str(fr_id,'%03d') + ".tiff";
                imwrite(img(:,:,i),fullfile(tiff_folder,tiff_name),'tif');
            end
            %end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.OpenSEQfileButton = uibutton(app.GridLayout3, 'push');
            app.OpenSEQfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSEQfileButtonPushed, true);
            app.OpenSEQfileButton.Text = 'Open SEQ file';
            app.RWDButton = uibutton(app.GridLayout3, 'push');
            app.RWDButton.Text = 'RWD';
            app.PlayPauseButton = uibutton(app.GridLayout3, 'push');
            app.PlayPauseButton.Text = 'Play/Pause';
            app.FFWDButton = uibutton(app.GridLayout3, 'push');
            app.FFWDButton.Text = 'FFWD';
            app.CurrentFrameSpinnerLabel = uilabel(app.GridLayout3);
            app.CurrentFrameSpinnerLabel.Text = 'Current Frame';
            app.CurrentFrameSpinner = uispinner(app.GridLayout3);
            app.CurrentFrameSpinner.ValueChangingFcn = createCallbackFcn(app, @CurrentFrameSpinnerValueChanged, true);
            app.CurrentFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameSpinnerValueChanged, true);
            app.FrameCountEditFieldLabel = uilabel(app.GridLayout3);
            app.FrameCountEditFieldLabel.Text = 'Frame Count';
            app.FrameCountEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Slider_2 = uislider(app.GridLayout3, 'range');
            app.Slider_2.Limits = [1 100];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = [1 100];
            app.FileEditFieldLabel = uilabel(app.GridLayout3);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.GridLayout3, 'text');
            app.ExportTIFFButton = uibutton(app.GridLayout3, 'push');
            app.ExportTIFFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTIFFButtonPushed, true);
            app.ExportTIFFButton.Text = 'Export TIFF';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seq_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end